classdef GeneticAlgorithim < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        GeneticAlgorithmResultTextArea  matlab.ui.control.TextArea
        GeneticAlgorithmResultTextAreaLabel  matlab.ui.control.Label
        GeneticAlgorithmTestingAppLabel  matlab.ui.control.Label
        ExhaustiveCheckResultsTextArea  matlab.ui.control.TextArea
        ExhaustiveCheckResultsTextAreaLabel  matlab.ui.control.Label
        RunGeneticAlgorithmButton       matlab.ui.control.Button
        TournamentKEditField            matlab.ui.control.NumericEditField
        TournamentKEditFieldLabel       matlab.ui.control.Label
        EletismCountEditField           matlab.ui.control.NumericEditField
        EletismCountEditFieldLabel      matlab.ui.control.Label
        MutationProbabilityEditField    matlab.ui.control.NumericEditField
        MutationProbabilityEditFieldLabel  matlab.ui.control.Label
        CrossoverProbabilityEditField   matlab.ui.control.NumericEditField
        CrossoverProbabilityEditFieldLabel  matlab.ui.control.Label
        GenerationsEditField            matlab.ui.control.NumericEditField
        GenerationsEditFieldLabel       matlab.ui.control.Label
        PopulationSizeEditField         matlab.ui.control.NumericEditField
        PopulationSizeEditFieldLabel    matlab.ui.control.Label
        PartSelectorDropDown            matlab.ui.control.DropDown
        PartSelectorDropDownLabel       matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RunGeneticAlgorithmButton
        function RunGeneticAlgorithmButtonPushed(app, event)
            % Push GUI values into MATLAB base workspace
    assignin('base', 'whichPart', app.PartSelectorDropDown.Value);
    assignin('base', 'popSize', app.PopulationSizeEditField.Value);
    assignin('base', 'numGenerations', app.GenerationsEditField.Value);
    assignin('base', 'pCrossover', app.CrossoverProbabilityEditField.Value);
    assignin('base', 'pMutation', app.MutationProbabilityEditField.Value);
    assignin('base', 'elitismCount', app.EletismCountEditField.Value);
    assignin('base', 'tournamentK', app.TournamentKEditField.Value);
    
    % Run your existing script
    try
        evalin('base','clear exMsg gaMsg');  % clean old copies
        evalin('base','run(''Assignment3Script.m'');');  % your script file

        % 3) fetch the two sections
        if evalin('base',"exist('exMsg','var')"), ex = evalin('base','exMsg'); else, ex = "No exhaustive output."; end
        if evalin('base',"exist('gaMsg','var')"), ga = evalin('base','gaMsg'); else, ga = "No GA output."; end

        % 4) display in app (split lines for neat wrapping)
        app.ExhaustiveCheckResultsTextArea.Value = splitlines(string(ex));
        app.GeneticAlgorithmResultTextArea.Value         = splitlines(string(ga));
    catch ME
        app.ExhaustiveCheckResultsTextArea.Value  = {'Error running script:', ME.message};
    end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.PartSelectorDropDownLabel = uilabel(app.GridLayout);
            app.PartSelectorDropDownLabel.Text = 'Part Selector ';
            app.PartSelectorDropDown = uidropdown(app.GridLayout);
            app.PartSelectorDropDown.Items = {'1: No River', '2: River at x=5'};
            app.PartSelectorDropDown.ItemsData = {'1', '2'};
            app.PartSelectorDropDown.Value = '1';
            app.PopulationSizeEditFieldLabel = uilabel(app.GridLayout);
            app.PopulationSizeEditFieldLabel.Text = 'Population Size';
            app.PopulationSizeEditField = uieditfield(app.GridLayout, 'numeric');
            app.PopulationSizeEditField.Limits = [0 50];
            app.PopulationSizeEditField.Value = 30;
            app.GenerationsEditFieldLabel = uilabel(app.GridLayout);
            app.GenerationsEditFieldLabel.Text = 'Generations';
            app.GenerationsEditField = uieditfield(app.GridLayout, 'numeric');
            app.GenerationsEditField.Limits = [0 500];
            app.GenerationsEditField.Value = 200;
            app.CrossoverProbabilityEditFieldLabel = uilabel(app.GridLayout);
            app.CrossoverProbabilityEditFieldLabel.Text = 'Crossover Probability';
            app.CrossoverProbabilityEditField = uieditfield(app.GridLayout, 'numeric');
            app.CrossoverProbabilityEditField.Limits = [0 1];
            app.CrossoverProbabilityEditField.Value = 0.9;
            app.MutationProbabilityEditFieldLabel = uilabel(app.GridLayout);
            app.MutationProbabilityEditFieldLabel.Text = 'Mutation Probability';
            app.MutationProbabilityEditField = uieditfield(app.GridLayout, 'numeric');
            app.MutationProbabilityEditField.Limits = [0 1];
            app.MutationProbabilityEditField.Value = 0.15;
            app.EletismCountEditFieldLabel = uilabel(app.GridLayout);
            app.EletismCountEditFieldLabel.Text = 'Eletism Count';
            app.EletismCountEditField = uieditfield(app.GridLayout, 'numeric');
            app.EletismCountEditField.Limits = [0 10];
            app.EletismCountEditField.Value = 2;
            app.TournamentKEditFieldLabel = uilabel(app.GridLayout);
            app.TournamentKEditFieldLabel.Text = 'Tournament K';
            app.TournamentKEditField = uieditfield(app.GridLayout, 'numeric');
            app.TournamentKEditField.Limits = [0 10];
            app.TournamentKEditField.Value = 2;
            app.RunGeneticAlgorithmButton = uibutton(app.GridLayout, 'push');
            app.RunGeneticAlgorithmButton.ButtonPushedFcn = createCallbackFcn(app, @RunGeneticAlgorithmButtonPushed, true);
            app.RunGeneticAlgorithmButton.Text = 'Run Genetic Algorithm';
            app.ExhaustiveCheckResultsTextAreaLabel = uilabel(app.GridLayout);
            app.ExhaustiveCheckResultsTextAreaLabel.Text = 'Exhaustive Check Results';
            app.ExhaustiveCheckResultsTextArea = uitextarea(app.GridLayout);
            app.GeneticAlgorithmTestingAppLabel = uilabel(app.GridLayout);
            app.GeneticAlgorithmTestingAppLabel.Text = 'Genetic Algorithm Testing App';
            app.GeneticAlgorithmResultTextAreaLabel = uilabel(app.GridLayout);
            app.GeneticAlgorithmResultTextAreaLabel.Text = 'Genetic Algorithm Result';
            app.GeneticAlgorithmResultTextArea = uitextarea(app.GridLayout);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GeneticAlgorithim

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end